classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        tempText     matlab.ui.control.TextArea
        Label        matlab.ui.control.Label
        tempState    matlab.ui.control.TextArea
        StartButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
        progStatus   matlab.ui.control.EditField
    end

    methods (Access = private)

        % Value changed function: tempText
        function tempTextValueChanged(app, event)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            a = arduino(); % Create Arduino object

            % Pin labels
            tempPin = 'A0';
            speakerPin = 'D6';
            
            temp = 1.0; % Holds the temperature reading.
            
            % Show that the thermometer is now on.
            app.progStatus.Value = 'ON';
            
            while(strcmp(app.progStatus.Value, 'ON'))
                
                % Read output voltage from temp sensor, and convert to temp in C.
                rawVoltage = readVoltage(a, tempPin);
                temp = (rawVoltage - 0.5) * 100;
                
                % Buzz the speaker if the temperature is above 25C, and show
                % the state on the GUI.
                app.tempText.Value = strcat(strcat('TEMP:  ', num2str(temp, 3)), ' C');
                if(temp >= 25.0)
                    playTone(a, speakerPin, 1500, 1);
                    app.tempState.Value = {'CRITICAL TEMP'};
                else
                    app.tempState.Value = {'Normal Temp'};
                end
                
                pause(0.5);
            end
            
            % Update display elements to show it does not know the temp.
            app.tempText.Value = {'TEMP: UNKNOWN'};
            app.tempState.Value = {'UNKNOWN STATE'};
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Set the thermometor flag off.
            app.progStatus.Value = 'OFF';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.tempText = uitextarea(app.UIFigure);
            app.tempText.ValueChangedFcn = createCallbackFcn(app, @tempTextValueChanged, true);
            app.tempText.Editable = 'off';
            app.tempText.Value = {'TEMP: UNKNOWN'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.tempState = uitextarea(app.UIFigure);
            app.tempState.Value = {'UNKNOWN STATE'};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.progStatus = uieditfield(app.UIFigure, 'text');
            app.progStatus.Value = 'OFF';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end